classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SetPointSlider          matlab.ui.control.Slider
        showSliderValSet        matlab.ui.control.Label
        EndprogramButton        matlab.ui.control.Button
        LogintotxtButton        matlab.ui.control.Button
        SETPOINTrkLabel         matlab.ui.control.Label
        ForwardButton           matlab.ui.control.StateButton
        BackwardButton          matlab.ui.control.StateButton
        Slider_2                matlab.ui.control.Slider
        MANIPULATIONmkLabel     matlab.ui.control.Label
        showSliderValMan        matlab.ui.control.Label
        ControlModeSwitchLabel  matlab.ui.control.Label
        ControlModeSwitch       matlab.ui.control.Switch
        STOPButton              matlab.ui.control.Button
        SetoperationroutineDropDownLabel  matlab.ui.control.Label
        SetOperationRoutine     matlab.ui.control.DropDown
        voltageGraph            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Arduino object
        a;
        % Aplication sample time
        sampleTime = 0.0338;
        
        % operation conditions
        bContinue;
        windowSamples = 75;
        dControlMode = 'Auto';
        
        % Motor pins
        dForwardPin = 'D10';
        dBackwardPin = 'D11';
        
        % Position potentiometers
        aPositionPin = 'A7';
        aForcePin = 'A8';
        
        % PID constants
        Kp = 15; %4.061;
        Kd = 0; %171.6;
        Ki = 2; %.8816;
        pseudoSetP = 0.5;
        
        % process vectors
        c = [];
        e = [];
        m = [];
        r = [];
        
        % Initialized boolean state
        bPIDinit = 0;
        
        
    end
    
    methods (Access = private)
        
        function results = forward_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,5);
            writePWMVoltage(app.a,app.dBackwardPin,0);
            
        end
        
        function results = stop_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,0);
        end
        
        function results = back_motor(app)
            writePWMVoltage(app.a,app.dForwardPin,0);
            writePWMVoltage(app.a,app.dBackwardPin,5);
        end
        
        function results = updatePlots(app,YLecture,SetPoint,Error )
            grid(app.voltageGraph,'on');
            
            
            plot(app.voltageGraph, YLecture,'--+','MarkerIndices',1:13:length(YLecture),'LineWidth', 1,'color',[0 1 0]);
            % Keep this line here to update new frames
            hold(app.voltageGraph,'on');
            
            plot(app.voltageGraph, SetPoint,'-*','MarkerIndices',1:7:length(YLecture),'LineWidth', 1,'color',[1 0 0]);
            plot(app.voltageGraph, Error,'-x','MarkerIndices',1:11:length(YLecture),'LineWidth', 1,'color',[0 0 1]);
            
            legend(app.voltageGraph,'y(k)','r(k)','e(k)','Location','SouthEast');
            yticks(app.voltageGraph, (-5:0.5:5));
            
            drawnow;
        end
        
        function results = initializePID(app)
            % Initialization of first samples
            v = readVoltage(app.a,app.aPositionPin);
            T = app.sampleTime;
            app.r(1) = app.pseudoSetP;
            app.c(1) = v;
            app.e(1) = app.pseudoSetP - app.c(1);
            app.m(1) = (1/(2*T))*((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(1));
            
            app.c(2) = v;
            app.r(2) = app.pseudoSetP;
            app.e(2) = app.pseudoSetP - app.c(2);
            app.m(2) = (1/(2*T))*((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(2) + (2*app.Ki*(T^2)-8*app.Kd)*app.e(1));
        end
        
        function results = runPID(app,k)
            v = readVoltage(app.a,app.aPositionPin);
            T = app.sampleTime;
            app.c(k) = v;
            app.r(k) = app.pseudoSetP;
            app.e(k) = app.pseudoSetP - app.c(k);
            app.m(k) = (1/(2*T))*((2*T*app.m(k-2)) + ((2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k)) + ((2*app.Ki*(T^2)-8*app.Kd)*app.e(k-1)) + ((-2*T*app.Kp+app.Ki*(T^2)+4*app.Kd)*app.e(k-2)));
            
            % plot
            app.updatePlots(app.c, app.r,app.e);
            
            % Control
            if app.e(k) > 0
                if abs(app.m(k)) > 5
                    writePWMVoltage(app.a, app.dForwardPin, 5);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                elseif abs(app.m(k)) > 2
                    writePWMVoltage(app.a, app.dForwardPin, abs(app.m(k)));
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                else
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                end
            else
                if abs(app.m(k)) > 5
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 5);
                elseif abs(app.m(k)) > 2
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, abs(app.m(k)));
                else
                    writePWMVoltage(app.a, app.dForwardPin, 0);
                    writePWMVoltage(app.a, app.dBackwardPin, 0);
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialice ARDUINO object
            pause(2);
            app.a = arduino('/dev/cu.usbmodem1421','Mega2560');
            % app.a = arduino();
            
            % Defining matrix
            lectures = [];
            setPoint = [];
            err = [];
            ksample = 1;
            
            
            % Setting constraints limits
            app.voltageGraph.XLim = [0 app.windowSamples];
            app.voltageGraph.YLim = [-5 5];
            
            % Variable to end program
            app.bContinue = true;
            
            % Aplication loop
            while(app.bContinue)
                % clear view
                if(ksample > app.windowSamples)
                    lectures = [];
                    setPoint = [];
                    err = [];
                    app.c = [];
                    app.e = [];
                    app.m = [];
                    app.r = [];
                    
                    ksample = 1;
                    app.bPIDinit = 0;
                    hold(app.voltageGraph,'off');
      
                end
                
                % -------------- %
                % OPERATION MODE %
                % -------------- %
                
                % MANUAL
                if(strcmp(app.dControlMode,'Manual'))
                    v = app.a.readVoltage(app.aPositionPin);
                    lectures = [lectures v];
                    setPoint = [setPoint, app.SetPointSlider.Value];
                    err = [err, (v-app.SetPointSlider.Value)];
                    % plot
                    app.updatePlots(lectures,setPoint,err);


                    % AUTOMATIC
                elseif(strcmp(app.dControlMode,'Auto'))
                    routine = app.SetOperationRoutine.Value;
                    
                    % run proper routine
                    if(strcmp(routine,'Reach Setpoint'))
                        if(app.bPIDinit == 0)
                            app.initializePID();
                            app.bPIDinit = 1;
                            ksample = 2;
                        else
                            app.runPID(ksample);
                        end
                        
                    elseif(strcpm(routine,'Ankle Gate'))
                    elseif(strcmp(routine,'Force against'))
                    elseif(strcmp(routine,'Force asist'))
                       
                    end                    
                end
                ksample = ksample + 1;
                % Time to plot and interrupt
                pause(0.0001);
            end


        end

        % Value changed function: SetPointSlider
        function SetPointSliderValueChanged(app, event)
            sliderValue = app.SetPointSlider.Value;
            
            % update setpoint
            if(app.SetOperationRoutine.Value == 'Reach Setpoint')
                app.pseudoSetP = sliderValue;
            end
            
            % show value
            app.showSliderValSet.Text = num2str(sliderValue);
            
        end

        % Button pushed function: EndprogramButton
        function EndprogramButtonPushed(app, event)
            app.bContinue = false;
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if(app.bPause == false)
                app.bPause = true;
                app.PauseButton.Text = 'Continue';
            else
                app.bPause = false;
                app.PauseButton.Text = 'Pause';
            end
        end

        % Value changed function: ForwardButton
        function ForwardButtonValueChanged(app, event)
            value = app.ForwardButton.Value;
            if(value == true)
                app.ForwardButton.Text = 'STOP';
                forward_motor(app);
            else
                app.ForwardButton.Text = 'Forward';
                stop_motor(app);
            end
        end

        % Value changed function: BackwardButton
        function BackwardButtonValueChanged(app, event)
            value = app.BackwardButton.Value;
            if(value == true)
                app.BackwardButton.Text = 'STOP';
                back_motor(app);
            else
                app.BackwardButton.Text = 'Backward';
                stop_motor(app);
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            stop_motor(app);
        end

        % Value changed function: ControlModeSwitch
        function ControlModeSwitchValueChanged(app, event)
            value = app.ControlModeSwitch.Value;
            app.dControlMode = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetPointSlider = uislider(app.UIFigure);
            app.SetPointSlider.Limits = [0.44 1.4];
            app.SetPointSlider.MajorTicks = [0.44 0.54 0.64 0.74 0.84 0.94 1.04 1.14 1.24 1.4];
            app.SetPointSlider.MajorTickLabels = {'min', '0.54', '0.64', '0.74', '0.84', '0.94', '1.04', '1.14', '1.24', 'max'};
            app.SetPointSlider.ValueChangedFcn = createCallbackFcn(app, @SetPointSliderValueChanged, true);
            app.SetPointSlider.Value = 0.44;
            app.showSliderValSet = uilabel(app.UIFigure);
            app.showSliderValSet.Text = '';
            app.EndprogramButton = uibutton(app.UIFigure, 'push');
            app.EndprogramButton.ButtonPushedFcn = createCallbackFcn(app, @EndprogramButtonPushed, true);
            app.EndprogramButton.Text = 'End program';
            app.LogintotxtButton = uibutton(app.UIFigure, 'push');
            app.LogintotxtButton.Text = 'Log into txt';
            app.SETPOINTrkLabel = uilabel(app.UIFigure);
            app.SETPOINTrkLabel.Text = {'SET POINT '; 'r(k)'};
            app.ForwardButton = uibutton(app.UIFigure, 'state');
            app.ForwardButton.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonValueChanged, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UIFigure, 'state');
            app.BackwardButton.ValueChangedFcn = createCallbackFcn(app, @BackwardButtonValueChanged, true);
            app.BackwardButton.Text = 'Backward';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 5];
            app.Slider_2.Value = 1;
            app.MANIPULATIONmkLabel = uilabel(app.UIFigure);
            app.MANIPULATIONmkLabel.Text = {'MANIPULATION'; 'm(k)'};
            app.showSliderValMan = uilabel(app.UIFigure);
            app.showSliderValMan.Text = '';
            app.ControlModeSwitchLabel = uilabel(app.UIFigure);
            app.ControlModeSwitchLabel.Text = 'Control Mode';
            app.ControlModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ControlModeSwitch.Items = {'Manual', 'Auto'};
            app.ControlModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlModeSwitchValueChanged, true);
            app.ControlModeSwitch.Value = 'Auto';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = '¡ STOP !';
            app.SetoperationroutineDropDownLabel = uilabel(app.UIFigure);
            app.SetoperationroutineDropDownLabel.Text = 'Set operation routine';
            app.SetOperationRoutine = uidropdown(app.UIFigure);
            app.SetOperationRoutine.Items = {'Ankle Gate', 'Force against', 'Froce asist', 'Reach Setpoint'};
            app.SetOperationRoutine.Value = 'Reach Setpoint';
            app.voltageGraph = uiaxes(app.UIFigure);
            title(app.voltageGraph, 'Control Ouput')
            xlabel(app.voltageGraph, 'sample(k)')
            ylabel(app.voltageGraph, 'Voltage')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end